classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MovementSpeedEvaluateBotGaugeLabel  matlab.ui.control.Label
        IgnitionEbotSwitchLabel        matlab.ui.control.Label
        IgnitionQBotSwitchLabel        matlab.ui.control.Label
        StopProgramButton              matlab.ui.control.Button
        MovementSpeedEvaluateBotGauge  matlab.ui.control.LinearGauge
        MovementSpeedQuenchBotGaugeLabel  matlab.ui.control.Label
        PauseProgramButton             matlab.ui.control.Button
        IgnitionEbotSwitch             matlab.ui.control.ToggleSwitch
        IgnitionQBotSwitch             matlab.ui.control.ToggleSwitch
        EmergencyButton                matlab.ui.control.StateButton
        WSlider                        matlab.ui.control.Slider
        WSliderLabel                   matlab.ui.control.Label
        MovementSpeedQuenchBotGauge    matlab.ui.control.LinearGauge
        StartProgramButton             matlab.ui.control.Button
        VSlider                        matlab.ui.control.Slider
        VSliderLabel                   matlab.ui.control.Label
        OperatingLamp                  matlab.ui.control.Lamp
        OperatingLampLabel             matlab.ui.control.Label
        USlider                        matlab.ui.control.Slider
        USliderLabel                   matlab.ui.control.Label
        IdleLamp                       matlab.ui.control.Lamp
        IdleLampLabel                  matlab.ui.control.Label
        ProgramSelectButtonGroup       matlab.ui.container.ButtonGroup
        DisposeButton                  matlab.ui.control.ToggleButton
        EvaluateButton                 matlab.ui.control.ToggleButton
        QuenchButton                   matlab.ui.control.ToggleButton
        PickupButton                   matlab.ui.control.ToggleButton
        MainButton                     matlab.ui.control.ToggleButton
        ZSlider                        matlab.ui.control.Slider
        ZSliderLabel                   matlab.ui.control.Label
        YSlider                        matlab.ui.control.Slider
        YSliderLabel                   matlab.ui.control.Label
        StoppedLamp                    matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        AxisControlButtonGroup         matlab.ui.container.ButtonGroup
        EvaluateBotButton              matlab.ui.control.RadioButton
        QuenchBotButton                matlab.ui.control.RadioButton
        XSlider                        matlab.ui.control.Slider
        XSliderLabel                   matlab.ui.control.Label
    end


    properties (Access = private)
        CurrentRobot  % Stores the currently selected robot (Excalibot or UR5)
        robot_excalibot %Excalibot class handler
        robot_UR %UR robot class handler
    end

    methods (Access = public)

        % Fetch current robot position and update sliders
        function updateSliders(app, robot)
            % Get the current position and rotation of the robot's end effector
            currentPosition = transl(robot.model.fkine(robot.model.getpos()));  % Get translation (x, y, z)
            currentRPY = tr2rpy(robot.model.fkine(robot.model.getpos()));       % Get rotation (roll, pitch, yaw)

            % Update sliders with current position
            app.XSlider.Value = currentPosition(1);  % X-axis
            app.YSlider.Value = currentPosition(2);  % Y-axis
            app.ZSlider.Value = currentPosition(3);  % Z-axis
            app.USlider.Value = rad2deg(currentRPY(1));  % Roll
            app.VSlider.Value = rad2deg(currentRPY(2));  % Pitch
            app.WSlider.Value = rad2deg(currentRPY(3));  % Yaw
        end

        % Function to move the robot based on slider values
        function moveRobotBasedOnSliders(app, robot)

            if robot == app.robot_excalibot
                ignition = app.IgnitionQBotSwitch;
            elseif robot == app.robot_UR
                ignition = app.IgnitionEbotSwitch;
            end

            if ignition.Value == "On" && app.EmergencyButton.Value == false
                % Fetch current slider values for position (X, Y, Z) and rotation (U, V, W)
                newPosition = [app.XSlider.Value, app.YSlider.Value, app.ZSlider.Value];
                newRotation = deg2rad([app.USlider.Value, app.VSlider.Value, app.WSlider.Value]);

                % Compute the transformation matrix from sliders
                newTransformation = transl(newPosition) * rpy2tr(newRotation);

                % Inverse kinematics to calculate the joint angles for the new position
                newJointAngles = robot.model.ikcon(newTransformation);

                % Create Trajectory for the movemement
                newTrajectory = jtraj(robot.model.getpos(),newJointAngles, 50);

                app.OperatingLamp.Enable = "on";
                app.IdleLamp.Enable = "off";
                app.StoppedLamp.Enable ="off";

                % Animate motion
                for i = 1:size(newTrajectory, 1)
                    % Move the robot using the new joint angles
                    if ignition.Value == "On" && app.EmergencyButton.Value == false
                        robot.model.animate(newTrajectory(i, :));
                    end
                    updateSliders(app, robot);
                    drawnow();
                end

                %Ending Operation
                updateSliders(app, robot);
                updateLights(app);
            else
                %Ending Operation if disabled
                updateSliders(app, robot);
                updateLights(app);
            end

        end

    end

    methods (Access = private)
        
        function updateLights(app)
            if app.EmergencyButton.Value == true
                app.OperatingLamp.Enable = "off";
                app.IdleLamp.Enable = "off";
                app.StoppedLamp.Enable ="on";
            else
                %Adjust Lamps QBot
                if app.IgnitionQBotSwitch.Value == "On"
                    if app.CurrentRobot == app.robot_excalibot
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "on";
                        app.StoppedLamp.Enable ="off";
                    end
                end

                if app.IgnitionQBotSwitch.Value == "Off"
                    if app.CurrentRobot == app.robot_excalibot
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "off";
                        app.StoppedLamp.Enable ="on";
                    end
                end
                %Adjust Lamps EBot
                if app.IgnitionEbotSwitch.Value == "On"
                    if app.CurrentRobot == app.robot_UR
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "on";
                        app.StoppedLamp.Enable ="off";
                    end
                end

                if app.IgnitionEbotSwitch.Value == "Off"
                    if app.CurrentRobot == app.robot_UR
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "off";
                        app.StoppedLamp.Enable ="on";
                    end
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%% System Start    %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            startup_rvc;
            clc; close all;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%   Initialize Environment  %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Environment;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%  Excalibot   %%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%Select Starting Position Excalibot%%%%%%%%%
            % translation part
            position_Excalibot = [0.5, 0.68, 0]; % x, y, z coordinates

            % rotation part
            roll_Excalibot = deg2rad(0); % Roll (rotation about x-axis)
            pitch_Excalibot = deg2rad(0); % Pitch (rotation about y-axis)
            yaw_Excalibot = deg2rad(0); % Yaw (rotation about z-axis)

            % transformation matrix using transl and rpy2tr
            baseTr_Excalibot = transl(position_Excalibot) * rpy2tr(roll_Excalibot, pitch_Excalibot, yaw_Excalibot);

            %%%%%%%%%% Create an instance of the Excalibot class %%%%%%%%
            app.robot_excalibot = Excalibot(baseTr_Excalibot);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%    UR  %%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%Select Starting Position UR%%%%%%%%%
            % translation part
            position_UR = [-0.494, -0.242, 0.581113]; % x, y, z coordinates

            % rotation part
            roll_UR = deg2rad(0); % Roll (rotation about x-axis)
            pitch_UR = deg2rad(0); % Pitch (rotation about y-axis)
            yaw_UR = deg2rad(0); % Yaw (rotation about z-axis)

            % transformation matrix using transl and rpy2tr
            baseTr_UR = transl(position_UR) * rpy2tr(roll_UR, pitch_UR, yaw_UR);

            %%%%%%%%%% Create an instance of the URx class %%%%%%%%
            app.robot_UR = UR5(baseTr_UR);

            %%%%%%%%Initialize slider control%%%%%%%%%%
            % Set initial robot as Excalibot
            app.CurrentRobot = app.robot_excalibot;

            % Initialize sliders with Excalibot's position
            updateSliders(app, app.CurrentRobot);

            %%%%Initialize Lights%%%%%%
            updateLights(app);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clc; close all;
        end

        % Value changed function: IgnitionQBotSwitch
        function IgnitionQBotSwitchValueChanged(app, event)
            updateLights(app);
        end

        % Value changed function: IgnitionEbotSwitch
        function IgnitionEbotSwitchValueChanged(app, event)
            updateLights(app);
        end

        % Selection changed function: AxisControlButtonGroup
        function AxisControlButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxisControlButtonGroup.SelectedObject;

            if selectedButton == app.QuenchBotButton
                % QuenchBot (Excalibot) selected
                updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
                app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
                updateLights(app);
            elseif selectedButton == app.EvaluateBotButton
                % EvaluateBot (UR5) selected
                updateSliders(app, app.robot_UR);  % Update sliders based on UR5's position
                app.CurrentRobot = app.robot_UR;  % Store the selected robot
                updateLights(app);
            end

        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: USlider
        function USliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: VSlider
        function VSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: WSlider
        function WSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: EmergencyButton
        function EmergencyButtonValueChanged(app, event)
            updateLights(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

        end

        % Selection changed function: ProgramSelectButtonGroup
        function ProgramSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProgramSelectButtonGroup.SelectedObject;

        end

        % Button pushed function: StartProgramButton
        function StartProgramButtonPushed(app, event)

        end

        % Button pushed function: PauseProgramButton
        function PauseProgramButtonPushed(app, event)

        end

        % Button pushed function: StopProgramButton
        function StopProgramButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-5 5];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.AxisControlButtonGroup = uibuttongroup(app.UIFigure);
            app.AxisControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxisControlButtonGroupSelectionChanged, true);
            app.AxisControlButtonGroup.Title = 'AxisControl';
            app.QuenchBotButton = uiradiobutton(app.AxisControlButtonGroup);
            app.QuenchBotButton.Text = 'QuenchBot';
            app.QuenchBotButton.Value = true;
            app.EvaluateBotButton = uiradiobutton(app.AxisControlButtonGroup);
            app.EvaluateBotButton.Text = 'EvaluateBot';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Stopped';
            app.StoppedLamp = uilamp(app.UIFigure);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-5 5];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-5 5];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ProgramSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.ProgramSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProgramSelectButtonGroupSelectionChanged, true);
            app.ProgramSelectButtonGroup.Title = 'ProgramSelect';
            app.MainButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.MainButton.Text = 'Main';
            app.MainButton.Value = true;
            app.PickupButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.PickupButton.Text = 'Pickup';
            app.QuenchButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.QuenchButton.Text = 'Quench';
            app.EvaluateButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.EvaluateButton.Text = 'Evaluate';
            app.DisposeButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.DisposeButton.Text = 'Dispose';
            app.IdleLampLabel = uilabel(app.UIFigure);
            app.IdleLampLabel.Text = 'Idle';
            app.IdleLamp = uilamp(app.UIFigure);
            app.USliderLabel = uilabel(app.UIFigure);
            app.USliderLabel.Text = 'U';
            app.USlider = uislider(app.UIFigure);
            app.USlider.Limits = [-180 180];
            app.USlider.ValueChangedFcn = createCallbackFcn(app, @USliderValueChanged, true);
            app.OperatingLampLabel = uilabel(app.UIFigure);
            app.OperatingLampLabel.Text = 'Operating';
            app.OperatingLamp = uilamp(app.UIFigure);
            app.VSliderLabel = uilabel(app.UIFigure);
            app.VSliderLabel.Text = 'V';
            app.VSlider = uislider(app.UIFigure);
            app.VSlider.Limits = [-180 180];
            app.VSlider.ValueChangedFcn = createCallbackFcn(app, @VSliderValueChanged, true);
            app.StartProgramButton = uibutton(app.UIFigure, 'push');
            app.StartProgramButton.ButtonPushedFcn = createCallbackFcn(app, @StartProgramButtonPushed, true);
            app.StartProgramButton.Text = 'StartProgram';
            app.MovementSpeedQuenchBotGauge = uigauge(app.UIFigure, 'linear');
            app.WSliderLabel = uilabel(app.UIFigure);
            app.WSliderLabel.Text = 'W';
            app.WSlider = uislider(app.UIFigure);
            app.WSlider.Limits = [-180 180];
            app.WSlider.ValueChangedFcn = createCallbackFcn(app, @WSliderValueChanged, true);
            app.EmergencyButton = uibutton(app.UIFigure, 'state');
            app.EmergencyButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyButtonValueChanged, true);
            app.EmergencyButton.Icon = fullfile(pathToMLAPP, 'emergency-stop-warning-sign-s-1182.png');
            app.EmergencyButton.Text = '';
            app.IgnitionQBotSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IgnitionQBotSwitch.ValueChangedFcn = createCallbackFcn(app, @IgnitionQBotSwitchValueChanged, true);
            app.IgnitionEbotSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IgnitionEbotSwitch.ValueChangedFcn = createCallbackFcn(app, @IgnitionEbotSwitchValueChanged, true);
            app.PauseProgramButton = uibutton(app.UIFigure, 'push');
            app.PauseProgramButton.ButtonPushedFcn = createCallbackFcn(app, @PauseProgramButtonPushed, true);
            app.PauseProgramButton.Text = 'PauseProgram';
            app.MovementSpeedQuenchBotGaugeLabel = uilabel(app.UIFigure);
            app.MovementSpeedQuenchBotGaugeLabel.Text = 'MovementSpeedQuenchBot';
            app.MovementSpeedEvaluateBotGauge = uigauge(app.UIFigure, 'linear');
            app.StopProgramButton = uibutton(app.UIFigure, 'push');
            app.StopProgramButton.ButtonPushedFcn = createCallbackFcn(app, @StopProgramButtonPushed, true);
            app.StopProgramButton.Text = 'StopProgram';
            app.IgnitionQBotSwitchLabel = uilabel(app.UIFigure);
            app.IgnitionQBotSwitchLabel.Text = 'IgnitionQBot';
            app.IgnitionEbotSwitchLabel = uilabel(app.UIFigure);
            app.IgnitionEbotSwitchLabel.Text = 'IgnitionEbot';
            app.MovementSpeedEvaluateBotGaugeLabel = uilabel(app.UIFigure);
            app.MovementSpeedEvaluateBotGaugeLabel.Text = 'MovementSpeedEvaluateBot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end